classdef rover_setup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LeftRockerMinValueEditField_5Label  matlab.ui.control.Label
        LeftRockerMinValueEditField_5   matlab.ui.control.NumericEditField
        RightRockerMinValueEditField_5Label  matlab.ui.control.Label
        RightRockerMinValueEditField_5  matlab.ui.control.NumericEditField
        LeftBogieMinValueEditField_5Label  matlab.ui.control.Label
        LeftBogieMinValueEditField_5    matlab.ui.control.NumericEditField
        RightBogieMinValueEditField_5Label  matlab.ui.control.Label
        RightBogieMinValueEditField_5   matlab.ui.control.NumericEditField
        BogieHeightMinValueEditFieldLabel  matlab.ui.control.Label
        BogieHeightMinValueEditField    matlab.ui.control.NumericEditField
        CGHeightMinValueEditFieldLabel  matlab.ui.control.Label
        CGHeightMinValueEditField       matlab.ui.control.NumericEditField
        LeftRockerMaxValueEditFieldLabel  matlab.ui.control.Label
        LeftRockerMaxValueEditField     matlab.ui.control.NumericEditField
        CGHeightMaxValueEditFieldLabel  matlab.ui.control.Label
        CGHeightMaxValueEditField       matlab.ui.control.NumericEditField
        BogieHeightMaxValueEditFieldLabel  matlab.ui.control.Label
        BogieHeightMaxValueEditField    matlab.ui.control.NumericEditField
        RightBogieMaxValueEditFieldLabel  matlab.ui.control.Label
        RightBogieMaxValueEditField     matlab.ui.control.NumericEditField
        LeftBogieMaxValueEditFieldLabel  matlab.ui.control.Label
        LeftBogieMaxValueEditField      matlab.ui.control.NumericEditField
        RightRockerMaxValueEditFieldLabel  matlab.ui.control.Label
        RightRockerMaxValueEditField    matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        LeftRockerNominalEditField_2Label  matlab.ui.control.Label
        LeftRockerNominalEditField_2    matlab.ui.control.NumericEditField
        CGHeightNominalEditFieldLabel   matlab.ui.control.Label
        CGHeightNominalEditField        matlab.ui.control.NumericEditField
        BogieHeightNominalEditFieldLabel  matlab.ui.control.Label
        BogieHeightNominalEditField     matlab.ui.control.NumericEditField
        RightBogieNominalEditFieldLabel  matlab.ui.control.Label
        RightBogieNominalEditField      matlab.ui.control.NumericEditField
        LeftBogieNominalEditField_2Label  matlab.ui.control.Label
        LeftBogieNominalEditField_2     matlab.ui.control.NumericEditField
        RightRockerNominalEditFieldLabel  matlab.ui.control.Label
        RightRockerNominalEditField     matlab.ui.control.NumericEditField
        UpdateButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Init(app, mainapp, noms, lims)
             app.CallingApp = mainapp;
             app.LeftRockerNominalEditField_2.Value = noms(1);
             app.RightRockerNominalEditField.Value = noms(2);
             app.LeftBogieNominalEditField_2.Value = noms(3);
             app.RightBogieNominalEditField.Value = noms(4);
             app.BogieHeightNominalEditField.Value = noms(5);
             app.CGHeightNominalEditField.Value = noms(6);
             app.LeftRockerMinValueEditField_5.Value = lims(1, 1);
             app.RightRockerMinValueEditField_5.Value = lims(2, 1);
             app.LeftBogieMinValueEditField_5.Value = lims(3, 1);
             app.RightBogieMinValueEditField_5.Value = lims(4, 1);
             app.BogieHeightMinValueEditField.Value = lims(5, 1);
             app.CGHeightMinValueEditField.Value = lims(6, 1);
             app.LeftRockerMaxValueEditField.Value = lims(1, 2);
             app.RightRockerMaxValueEditField.Value = lims(2, 2);
             app.LeftBogieMaxValueEditField.Value = lims(3, 2);
             app.RightBogieMaxValueEditField.Value = lims(4, 2);
             app.BogieHeightMaxValueEditField.Value = lims(5, 2);
             app.CGHeightMaxValueEditField.Value = lims(6, 2);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            nn = [app.LeftRockerNominalEditField_2.Value, app.RightRockerNominalEditField.Value, app.LeftBogieNominalEditField_2.Value, app.RightBogieNominalEditField.Value, app.BogieHeightNominalEditField.Value, app.CGHeightNominalEditField.Value];
            nl = [app.RightRockerMinValueEditField_5.Value, app.RightRockerMaxValueEditField.Value; app.LeftRockerMinValueEditField_5.Value, app.LeftRockerMaxValueEditField.Value; app.RightBogieMinValueEditField_5.Value, app.RightBogieMaxValueEditField.Value; app.LeftBogieMinValueEditField_5.Value, app.LeftBogieMaxValueEditField.Value; app.BogieHeightMinValueEditField.Value, app.BogieHeightMaxValueEditField.Value; app.CGHeightMinValueEditField.Value, app.CGHeightMaxValueEditField.Value];
            updaterover(app.CallingApp, nn, nl);
            app.CallingApp.RoverSetupButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.RoverSetupButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftRockerMinValueEditField_5Label = uilabel(app.UIFigure);
            app.LeftRockerMinValueEditField_5Label.Text = 'Left Rocker - Min Value';
            app.LeftRockerMinValueEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.LeftRockerMinValueEditField_5.Value = 400;
            app.RightRockerMinValueEditField_5Label = uilabel(app.UIFigure);
            app.RightRockerMinValueEditField_5Label.Text = 'Right Rocker - Min Value';
            app.RightRockerMinValueEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.RightRockerMinValueEditField_5.Value = 180;
            app.LeftBogieMinValueEditField_5Label = uilabel(app.UIFigure);
            app.LeftBogieMinValueEditField_5Label.Text = 'Left Bogie - Min Value';
            app.LeftBogieMinValueEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.LeftBogieMinValueEditField_5.Value = 155;
            app.RightBogieMinValueEditField_5Label = uilabel(app.UIFigure);
            app.RightBogieMinValueEditField_5Label.Text = 'Right Bogie - Min Value';
            app.RightBogieMinValueEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.RightBogieMinValueEditField_5.Value = 155;
            app.BogieHeightMinValueEditFieldLabel = uilabel(app.UIFigure);
            app.BogieHeightMinValueEditFieldLabel.Text = 'Bogie Height - Min Value';
            app.BogieHeightMinValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.BogieHeightMinValueEditField.Value = 155;
            app.CGHeightMinValueEditFieldLabel = uilabel(app.UIFigure);
            app.CGHeightMinValueEditFieldLabel.Text = 'CG Height - Min Value';
            app.CGHeightMinValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.CGHeightMinValueEditField.Value = 25;
            app.LeftRockerMaxValueEditFieldLabel = uilabel(app.UIFigure);
            app.LeftRockerMaxValueEditFieldLabel.Text = 'Left Rocker - Max Value';
            app.LeftRockerMaxValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftRockerMaxValueEditField.Value = 600;
            app.CGHeightMaxValueEditFieldLabel = uilabel(app.UIFigure);
            app.CGHeightMaxValueEditFieldLabel.Text = 'CG Height - Max Value';
            app.CGHeightMaxValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.CGHeightMaxValueEditField.Value = 200;
            app.BogieHeightMaxValueEditFieldLabel = uilabel(app.UIFigure);
            app.BogieHeightMaxValueEditFieldLabel.Text = 'Bogie Height - Max Value';
            app.BogieHeightMaxValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.BogieHeightMaxValueEditField.Value = 200;
            app.RightBogieMaxValueEditFieldLabel = uilabel(app.UIFigure);
            app.RightBogieMaxValueEditFieldLabel.Text = 'Right Bogie - Max Value';
            app.RightBogieMaxValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightBogieMaxValueEditField.Value = 300;
            app.LeftBogieMaxValueEditFieldLabel = uilabel(app.UIFigure);
            app.LeftBogieMaxValueEditFieldLabel.Text = 'Left Bogie - Max Value';
            app.LeftBogieMaxValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftBogieMaxValueEditField.Value = 300;
            app.RightRockerMaxValueEditFieldLabel = uilabel(app.UIFigure);
            app.RightRockerMaxValueEditFieldLabel.Text = 'Right Rocker - Max Value';
            app.RightRockerMaxValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightRockerMaxValueEditField.Value = 600;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'RockerBogie.PNG';
            app.LeftRockerNominalEditField_2Label = uilabel(app.UIFigure);
            app.LeftRockerNominalEditField_2Label.Text = 'Left Rocker - Nominal';
            app.LeftRockerNominalEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LeftRockerNominalEditField_2.Value = 400;
            app.CGHeightNominalEditFieldLabel = uilabel(app.UIFigure);
            app.CGHeightNominalEditFieldLabel.Text = 'CG Height - Nominal';
            app.CGHeightNominalEditField = uieditfield(app.UIFigure, 'numeric');
            app.CGHeightNominalEditField.Value = 94;
            app.BogieHeightNominalEditFieldLabel = uilabel(app.UIFigure);
            app.BogieHeightNominalEditFieldLabel.Text = 'Bogie Height - Nominal';
            app.BogieHeightNominalEditField = uieditfield(app.UIFigure, 'numeric');
            app.BogieHeightNominalEditField.Value = 170;
            app.RightBogieNominalEditFieldLabel = uilabel(app.UIFigure);
            app.RightBogieNominalEditFieldLabel.Text = 'Right Bogie - Nominal';
            app.RightBogieNominalEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightBogieNominalEditField.Value = 225;
            app.LeftBogieNominalEditField_2Label = uilabel(app.UIFigure);
            app.LeftBogieNominalEditField_2Label.Text = 'Left Bogie - Nominal';
            app.LeftBogieNominalEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LeftBogieNominalEditField_2.Value = 225;
            app.RightRockerNominalEditFieldLabel = uilabel(app.UIFigure);
            app.RightRockerNominalEditFieldLabel.Text = 'Right Rocker - Nominal';
            app.RightRockerNominalEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightRockerNominalEditField.Value = 180;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rover_setup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Init(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end