classdef stair_setup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RoadTypeDropDownLabel  matlab.ui.control.Label
        RoadTypeDropDown       matlab.ui.control.DropDown
        UIAxes                 matlab.ui.control.UIAxes
        TreadEditFieldLabel    matlab.ui.control.Label
        TreadEditField         matlab.ui.control.NumericEditField
        RiserEditFieldLabel    matlab.ui.control.Label
        RiserEditField         matlab.ui.control.NumericEditField
        UpdateButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp
        stair
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, stair)
            app.CallingApp = mainapp;
            app.stair = stairs(stair.tread, stair.riser, 9.8065);
            app.stair.app_draw(app, 1500);
            app.TreadEditField.Value = stair.tread;
            app.RiserEditField.Value = stair.riser;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.stair.tread = app.TreadEditField.Value;
            app.stair.riser = app.RiserEditField.Value;
            updatestair(app.CallingApp, app.stair);
            app.stair.app_draw(app, 1200);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.RoadSetupButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RoadTypeDropDownLabel = uilabel(app.UIFigure);
            app.RoadTypeDropDownLabel.Text = 'Road Type';
            app.RoadTypeDropDown = uidropdown(app.UIFigure);
            app.RoadTypeDropDown.Items = {'Stair'};
            app.RoadTypeDropDown.Value = 'Stair';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Stair Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TreadEditFieldLabel = uilabel(app.UIFigure);
            app.TreadEditFieldLabel.Text = 'Tread';
            app.TreadEditField = uieditfield(app.UIFigure, 'numeric');
            app.TreadEditField.Value = 240;
            app.RiserEditFieldLabel = uilabel(app.UIFigure);
            app.RiserEditFieldLabel.Text = 'Riser';
            app.RiserEditField = uieditfield(app.UIFigure, 'numeric');
            app.RiserEditField.Value = 200;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stair_setup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end