classdef suspension_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RUNOPTIMIZATIONButton          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ReadyLampLabel                 matlab.ui.control.Label
        ReadyLamp                      matlab.ui.control.Lamp
        RoverSetupButton               matlab.ui.control.Button
        RoadSetupButton                matlab.ui.control.Button
        LogTextAreaLabel               matlab.ui.control.Label
        LogTextArea                    matlab.ui.control.TextArea
        TrialResEditFieldLabel         matlab.ui.control.Label
        TrialResEditField              matlab.ui.control.NumericEditField
        IterationNumberEditFieldLabel  matlab.ui.control.Label
        IterationNumberEditField       matlab.ui.control.NumericEditField
        CGPathButton                   matlab.ui.control.Button
        AnimateButton                  matlab.ui.control.Button
        ConfigNumEditFieldLabel        matlab.ui.control.Label
        ConfigNumEditField             matlab.ui.control.NumericEditField
        UpdateButton                   matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
    end

    
    properties (Access = private)
        R 
        robert
        st
        RoverApp
        RoadApp
    end
    
    properties (Access = public)
        noms
        lims
    end
    
    methods (Access = public)
        
        function updaterover(app, newnoms, newlims)
            app.noms = newnoms;
            app.lims = newlims;
            app.robert.AssignGeometry(app.noms(1), app.noms(2), app.noms(3), app.noms(4), app.noms(5), app.noms(6), app.R, 1);
        end
        
        function updatestair(app, stair)
            app.st = stair;
        end
        
        function drawbasic(app)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            app.st.app_draw(app, 1200);
            app.robert.appdraw(app, app.ConfigNumEditField.Value, 1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Init(app)
            app.ReadyLamp.Color = 'red';
            app.R = [150, 150, 150];
            app.noms = [481, 194, 225, 225, 170, 94];
            app.lims = [400 600; 180 600; 155 300; 155 300; 155 200; 25 200];
            m = [2.8, 1.2, 30.8];
            app.robert = rockerbogie(app.noms, app.R, m, 1200);
            app.st = stairs(240, 200, 9.8065);
        end

        % Button pushed function: RUNOPTIMIZATIONButton
        function RUNOPTIMIZATIONButtonPushed(app, event)
            app.ReadyLamp.Color = 'yellow';
            tic;
            pause(0.04);
            app.robert = app.robert.Optimize(1, app.st, app.R, app.lims, app.IterationNumberEditField.Value, app.TrialResEditField.Value, 0);
            app.ReadyLamp.Color = 'green';
            textarray{1} = 'Optimization complete.';
            textarray{2} = 'Min config num:';
            textarray{3} = num2str(app.robert.min);
            textarray{4} = 'Max config num:';
            textarray{5} = num2str(app.robert.max);
            textarray{6} = 'Run time:';
            textarray{7} = num2str(toc);
            app.LogTextArea.Value = textarray;
        end

        % Button pushed function: RoverSetupButton
        function RoverSetupButtonPushed(app, event)
            app.RoverSetupButton.Enable = 'off';
            app.RoverApp = rover_setup(app, app.noms, app.lims);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.RoadApp)
            delete(app.RoverApp)
            delete(app)
        end

        % Button pushed function: RoadSetupButton
        function RoadSetupButtonPushed(app, event)
            app.RoadSetupButton.Enable = 'off';
            app.RoadApp = stair_setup(app, app.st);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.drawbasic();
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            app.robert.appAnimate(app, app.st, app.ConfigNumEditField.Value);
        end

        % Button pushed function: CGPathButton
        function CGPathButtonPushed(app, event)
            if( strcmp(app.Switch.Value,'Ideal') )
                isIdeal = 1;
            else
                isIdeal = 0;
            end
            app.robert.plotCG(app, app.st, isIdeal, app.ConfigNumEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RUNOPTIMIZATIONButton = uibutton(app.UIFigure, 'push');
            app.RUNOPTIMIZATIONButton.ButtonPushedFcn = createCallbackFcn(app, @RUNOPTIMIZATIONButtonPushed, true);
            app.RUNOPTIMIZATIONButton.Text = 'RUN OPTIMIZATION';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.RoverSetupButton = uibutton(app.UIFigure, 'push');
            app.RoverSetupButton.ButtonPushedFcn = createCallbackFcn(app, @RoverSetupButtonPushed, true);
            app.RoverSetupButton.Text = 'Rover Setup';
            app.RoadSetupButton = uibutton(app.UIFigure, 'push');
            app.RoadSetupButton.ButtonPushedFcn = createCallbackFcn(app, @RoadSetupButtonPushed, true);
            app.RoadSetupButton.Text = 'Road Setup';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.TrialResEditFieldLabel = uilabel(app.UIFigure);
            app.TrialResEditFieldLabel.Text = 'Trial Res';
            app.TrialResEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialResEditField.Value = 5;
            app.IterationNumberEditFieldLabel = uilabel(app.UIFigure);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationNumberEditField.Value = 3;
            app.CGPathButton = uibutton(app.UIFigure, 'push');
            app.CGPathButton.ButtonPushedFcn = createCallbackFcn(app, @CGPathButtonPushed, true);
            app.CGPathButton.Text = 'CG Path';
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.ConfigNumEditFieldLabel = uilabel(app.UIFigure);
            app.ConfigNumEditFieldLabel.Text = 'Config Num';
            app.ConfigNumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfigNumEditField.Value = 1;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Ideal', 'Actual'};
            app.Switch.Value = 'Ideal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = suspension_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end